classdef finalProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputParametersTab              matlab.ui.container.Tab
        LaminateMaterialPropertiesPanel  matlab.ui.container.Panel
        E1EditFieldLabel                matlab.ui.control.Label
        E1EditField                     matlab.ui.control.NumericEditField
        E2EditFieldLabel                matlab.ui.control.Label
        E2EditField                     matlab.ui.control.NumericEditField
        G12EditFieldLabel               matlab.ui.control.Label
        G12EditField                    matlab.ui.control.NumericEditField
        v12EditFieldLabel               matlab.ui.control.Label
        v12EditField                    matlab.ui.control.NumericEditField
        alpha1EditFieldLabel            matlab.ui.control.Label
        alpha1EditField                 matlab.ui.control.NumericEditField
        alpha2EditFieldLabel            matlab.ui.control.Label
        alpha2EditField                 matlab.ui.control.NumericEditField
        DropDownE1                      matlab.ui.control.DropDown
        DropDownE2                      matlab.ui.control.DropDown
        DropDownG12                     matlab.ui.control.DropDown
        DropDownalpha1                  matlab.ui.control.DropDown
        DropDownalpha2                  matlab.ui.control.DropDown
        LaminateStrengthPropertiesPanel  matlab.ui.container.Panel
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        XprimeEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YprimeEditField                 matlab.ui.control.NumericEditField
        SEditFieldLabel                 matlab.ui.control.Label
        SEditField                      matlab.ui.control.NumericEditField
        StrengthStressLabel             matlab.ui.control.Label
        StrengthStrainLabel             matlab.ui.control.Label
        XeEditFieldLabel                matlab.ui.control.Label
        XeEditField                     matlab.ui.control.NumericEditField
        DropDownX                       matlab.ui.control.DropDown
        XeEditField_2Label              matlab.ui.control.Label
        XePrimeEditField                matlab.ui.control.NumericEditField
        YeEditFieldLabel                matlab.ui.control.Label
        YeEditField                     matlab.ui.control.NumericEditField
        YeEditField_2Label              matlab.ui.control.Label
        YePrimeEditField                matlab.ui.control.NumericEditField
        SeEditFieldLabel                matlab.ui.control.Label
        SeEditField                     matlab.ui.control.NumericEditField
        DropDownXprime                  matlab.ui.control.DropDown
        DropDownY                       matlab.ui.control.DropDown
        DropDownYprime                  matlab.ui.control.DropDown
        DropDownS                       matlab.ui.control.DropDown
        CompositeMechanicalLoadingConditionsPanel  matlab.ui.container.Panel
        NxEditFieldLabel                matlab.ui.control.Label
        NxEditField                     matlab.ui.control.NumericEditField
        NyEditFieldLabel                matlab.ui.control.Label
        NyEditField                     matlab.ui.control.NumericEditField
        NxyEditFieldLabel               matlab.ui.control.Label
        NxyEditField                    matlab.ui.control.NumericEditField
        MxEditFieldLabel                matlab.ui.control.Label
        MxEditField                     matlab.ui.control.NumericEditField
        MyEditFieldLabel                matlab.ui.control.Label
        MyEditField                     matlab.ui.control.NumericEditField
        MxyEditFieldLabel               matlab.ui.control.Label
        MxyEditField                    matlab.ui.control.NumericEditField
        DropDownNx                      matlab.ui.control.DropDown
        DropDownMx                      matlab.ui.control.DropDown
        DropDownNy                      matlab.ui.control.DropDown
        DropDownNxy                     matlab.ui.control.DropDown
        DropDownMy                      matlab.ui.control.DropDown
        DropDownMxy                     matlab.ui.control.DropDown
        CompositeAnalysisInputParametersLabel  matlab.ui.control.Label
        CompositeHygrothermalLoadingConditionsPanel  matlab.ui.container.Panel
        T0EditFieldLabel                matlab.ui.control.Label
        T0EditField                     matlab.ui.control.NumericEditField
        TfEditFieldLabel                matlab.ui.control.Label
        TfEditField                     matlab.ui.control.NumericEditField
        DropDownT0                      matlab.ui.control.DropDown
        DropDownTf                      matlab.ui.control.DropDown
        ExcelDataOptionalPanel          matlab.ui.container.Panel
        BrowseLabel                     matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        LoadLabel                       matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        DirecotryTextAreaLabel          matlab.ui.control.Label
        DirecotryTextArea               matlab.ui.control.TextArea
        Lamp                            matlab.ui.control.Lamp
        LaminatePropertiesPanel         matlab.ui.container.Panel
        LayupDelimitedEditFieldLabel    matlab.ui.control.Label
        LayupEditField                  matlab.ui.control.EditField
        SymmetricCheckBox               matlab.ui.control.CheckBox
        PlyThicknessEditFieldLabel      matlab.ui.control.Label
        PlyThicknessEditField           matlab.ui.control.NumericEditField
        DropDownPlyThickness            matlab.ui.control.DropDown
        RunProgramPanel                 matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        AnalysisOutputTab               matlab.ui.container.Tab
        StressPanel                     matlab.ui.container.Panel
        UITableStress                   matlab.ui.control.Table
        StrainPanel                     matlab.ui.container.Panel
        UITableStrain                   matlab.ui.control.Table
        MidplainStrainPanel             matlab.ui.container.Panel
        UITableMidplaneStrain           matlab.ui.control.Table
        MidplainCurveraturePanel        matlab.ui.container.Panel
        UITableMidplaneCurverature      matlab.ui.control.Table
        TotalLoadingPanel               matlab.ui.container.Panel
        UITableTotalLoads               matlab.ui.control.Table
        FailureCriteriaPanel            matlab.ui.container.Panel
        UITableFaliureCriteria          matlab.ui.control.Table
        SaveDataPanel                   matlab.ui.container.Panel
        WritetoExcelLabel               matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        FileNameOptionalEditFieldLabel  matlab.ui.control.Label
        FileNameOptionalEditField       matlab.ui.control.EditField
        EffectiveConstatnsPanel         matlab.ui.container.Panel
        UITableEffectiveLaminateConstants  matlab.ui.control.Table
        StressSelectionLabel            matlab.ui.control.Label
        StressSelectionSwitch           matlab.ui.control.Switch
        StrainSelectionLabel            matlab.ui.control.Label
        StrainSelectionSwitch           matlab.ui.control.Switch
        GlobalStressPlotsTab            matlab.ui.container.Tab
        GlobalStressPlotsPanel          matlab.ui.container.Panel
        GlobalStrainPlotsTab            matlab.ui.container.Tab
        GlobalStrainPlotsPanel          matlab.ui.container.Panel
    end

    
    properties (Access = private)
        % Defaul excel file name
        defaultFileName = 'AE535 - InputData.xlsm';  
        
        % Default file directory
        % Since file is in working directory, only file name is needed
        fileDirectory = 'AE535 - InputData.xlsm';
        
        % Names for edit all edit fields
        fieldNames = {'E1', 'E2', 'G12', 'v12', ...
                'alpha1', 'alpha2', 'X', 'Xprime', 'Y', 'Yprime', 'S', ...
                'Xe', 'XePrime', 'Ye', 'YePrime', 'Se', 'Nx', 'Ny', ...
                'Nxy', 'Mx', 'My', 'Mxy', 'PlyThickness', 'T0', 'Tf'};
        
        % Formatted output data
        exportData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % Pulling file name
            fileName = app.defaultFileName;
            
            % If proper .xlsm file is selcted for loading data
            if exist(fileName, "file") || ...
                    (strcmp(app.DirecotryTextArea.Value, ...
                    'No File Selected'))
            
                % Turn lamp green
                app.Lamp.Color = [0 1 0];
                
            else
                
                % Turn lamp red
                app.Lamp.Color = [1 0 0];
                
            end
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Getting file name and directory for selected .xlsm file
            [file, path] = uigetfile('*xlsm');
            
            % If no file is selected
            if isequal(file, 0)
                
                % Display text in text are
                app.DirecotryTextArea.Value = 'No File Selected';
                
                % If no file is selected and default file is available
                if exist(app.defaultFileName, "file")
                    
                    % Set file directory to default file
                    app.fileDirectory = app.defaultFileName;
                
                end
                
            else
                
                % Display full directory in text area
                app.DirecotryTextArea.Value = strcat(path, file);
                
                % Storing file directory
                app.fileDirectory = strcat(path, file);
                
            end
            
            % Make text area and lable visible
            app.DirecotryTextArea.Visible = 'on';
            app.DirecotryTextAreaLabel.Visible = 'on';
            
            % Changing lamp color based on wether proper file is selected
            % or not
            if exist(app.fileDirectory, "file") || ...
                    (strcmp(app.DirecotryTextArea.Value, ...
                    'No File Selected'))
            
                % Turn lamp green
                app.Lamp.Color = [0 1 0];
            else
                
                % Turn lamp red
                app.Lamp.Color = [1 0 0];
                
            end
             
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Material properties names
            editFieldNames.valueNames = app.fieldNames;
            
            % Creating edit field names for material properties
            for ii = 1:length(editFieldNames.valueNames)
                editFieldNames.completeNames{ii} = strcat(...
                    editFieldNames.valueNames{ii}, 'EditField');
                
            end
            
            % Getting values from material property edit fields and adding
            % to inputs structure
            for ii = 1:length(editFieldNames.completeNames)
                
                inputs.(editFieldNames.valueNames{ii}) = ...
                    app.(editFieldNames.completeNames{ii}).Value;
            end
            
            % Obtaining stacking sequence based on edit field
            if app.SymmetricCheckBox.Value == 1
                inputs.ss = ...
                [str2double(strsplit(app.LayupEditField.Value, '/')), ...
                flip(str2double(strsplit(app.LayupEditField.Value, '/')))];
                
            else
                
                inputs.ss = ...
                    str2double(strsplit(app.LayupEditField.Value, '/'));
            end
            
            % Material property unit conversions
            for ii = 1:3
                % If the selected unit is Msi
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, 'Msi') == 1
                    continue
                else
                    % Converting GPa to Msi
                    inputs.(editFieldNames.valueNames{ii}) = ...
                        inputs.(editFieldNames.valueNames{ii}) * ...
                        (145038 * 10 ^ -6);
                    
                end
            end
            
            % Alpha unit conversions
            for ii = 5:6
                % If the selected unit is 1/F
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, '1/F') == 1
                continue
                else
                    
                    % Converting 1/F to 1/C
                    inputs.(editFieldNames.valueNames{ii}) =( ...
                        ((1/(inputs.(editFieldNames.valueNames{ii}))) * ...
                        (9/5)) + 32) ^ -1;

                end
            end
                        
            % Strength unit conversion
            for ii = 7:11
                % If the selected unit is ksi
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, 'ksi') == 1
                    continue
                else
                    % Converting kPa to Msi
                    inputs.(editFieldNames.valueNames{ii}) = ...
                        inputs.(editFieldNames.valueNames{ii}) * ...
                        (145038 * 10 ^ -9);
                end
            end
            
            % Force unit conversion
            for ii = 17:19
               % If the selected unit is lb
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, 'lb') == 1
                    continue
                else
                    % Converting N to lbf
                    inputs.(editFieldNames.valueNames{ii}) = ...
                        inputs.(editFieldNames.valueNames{ii}) * ...
                        (.224809);
                end
            end
            
            % Moment unit conversion
            for ii = 20:22
                % If the selected unit is lbin
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, 'lbin') == 1
                    continue
                else
                    % Converting Nm to lbin
                    inputs.(editFieldNames.valueNames{ii}) = ...
                         inputs.(editFieldNames.valueNames{ii}) * ...
                        (8.85075);
                end   
            end
            
            % Thickness unit conversion
            if strcmp(app.(strcat('DropDown', ...
                    editFieldNames.valueNames{23})).Value, 'in') == 0
           
                % Converting mm to in
                inputs.(editFieldNames.valueNames{23}) = ...
                    inputs.(editFieldNames.valueNames{23}) * ...
                    (.0393701);
            end
            
            % Alpha unit conversions
            for ii = 24:25
                 % If the selected unit is F
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, '1/F') == 1
                    continue
                else
                    % Converting F to C
                    inputs.(editFieldNames.valueNames{ii}) = ...
                         inputs.(editFieldNames.valueNames{ii}) * ...
                        (9/5) + 32;
                end    
            end
            
            % Run complete composite analysis code
            [faliure, toExport, plotStress, plotStrain, z] = main(inputs);
            
            % Sharing propery
            app.exportData = toExport;
            
            % ----- Pupulationg Output Sheet -----
            
            % Pupulating global stress table
            app.UITableStress.Data = toExport.globStress;
            app.UITableStress.ColumnName = ...
                num2cell(1:length(toExport.globStress));
            app.UITableStress.RowName = {'sigmax', 'sigmay', ...
                'sigmaxy'};
            
            % Populating global strain table
            app.UITableStrain.Data = toExport.globStrain;
            app.UITableStrain.ColumnName = ...
                num2cell(1:length(toExport.globStrain));
            app.UITableStrain.RowName = {'epsx', 'epsy', ...
                'gammaxy'};
            
            % Populating midplane strain table
            app.UITableMidplaneStrain.Data = toExport.midplaneStrain;
            app.UITableMidplaneStrain.RowName = {'eps0x', 'eps0y', ...
                'eps0xy'};
            
            % Populating midplane curverature table
            app.UITableMidplaneCurverature.Data = ...
                toExport.midplaneCurverature;
            app.UITableMidplaneCurverature.RowName = {'kx', 'ky', 'kxy'};
            
            % Populating total loads table
            app.UITableTotalLoads.Data = [toExport.totalForces; ...
                toExport.totalMoments];
            app.UITableTotalLoads.RowName = {'Nx', 'Ny', 'Nxy', 'Mx', ...
                'My', 'Mxy'};
            
            % Populating faliure criteria table
            app.UITableFaliureCriteria.Data = [faliure(1).fail', ...
                faliure(2).fail', faliure(3).fail', faliure(4).fail'];
            app.UITableFaliureCriteria.RowName = ...
                num2cell(1:length(faliure(1).fail));
            app.UITableFaliureCriteria.ColumnName = {'Max Stress', ...
                'Max Strain', 'Tsai-Hill', 'Tsai-Wu'};
            
            % Populating effective laminate properties table
            app.UITableEffectiveLaminateConstants.Data = toExport.LEP;
            app.UITableEffectiveLaminateConstants.RowName = {'Ex', ...
                'Ey', 'Gxy', 'vxy', 'alphax', 'alphay'};


            % Stress Plots
            
            % Subplot axes
            axSig1 = subplot(3, 1, 1, 'Parent', ...
                app.GlobalStressPlotsPanel);
            axSig2 = subplot(3, 1, 2, 'Parent', ...
               app.GlobalStressPlotsPanel);
            axSig12 = subplot(3, 1, 3, 'Parent', ...
                app.GlobalStressPlotsPanel);
            
            stressStrainType = {'Longitudinal', 'Transverse', 'Shear'};
            axStressLabels = [axSig1, axSig2, axSig12];
            
            for ii = 1:length(stressStrainType)
                

                % Plotting stress vs. z-coordinate
                plot(axStressLabels(ii), ...
                    plotStress.(stressStrainType{ii}), z, '-o')
                
                
                titleText = strcat(stressStrainType{ii}, ' Stress');
                
                % Plot descriptors
                xlabel(axStressLabels(ii), ...
                    'Global Stress (psi)','fontsize',12, 'Interpreter', ...
                    'latex')
                ylabel(axStressLabels(ii), ...
                    'z-Location (in)','fontsize',12, 'Interpreter', ...
                    'latex');
                title(axStressLabels(ii), ...
                    titleText,'fontsize',14, 'Interpreter', 'latex');
                
            end
            
            % Strain Plots
            
             % Subplot axes
            axEps1 = subplot(3, 1, 1, 'Parent', ...
                app.GlobalStrainPlotsPanel);
            axEps2 = subplot(3, 1, 2, 'Parent', ...
               app.GlobalStrainPlotsPanel);
            axGamma12 = subplot(3, 1, 3, 'Parent', ...
                app.GlobalStrainPlotsPanel);
            
            axStrainLabels = [axEps1, axEps2, axGamma12];
            
            for ii = 1:length(stressStrainType)
                

                % Plotting stress vs. z-coordinate
                plot(axStrainLabels(ii), ...
                    plotStrain.(stressStrainType{ii}), z, '-o')
                
                
                titleText = strcat(stressStrainType{ii}, ' Stress');
                
                % Plot descriptors
                xlabel(axStrainLabels(ii), ...
                    'Global Stress (psi)','fontsize',12, 'Interpreter', ...
                    'latex')
                ylabel(axStrainLabels(ii), ...
                    'z-Location (in)','fontsize',12, 'Interpreter',...
                    'latex');
                title(axStrainLabels(ii), ...
                    titleText,'fontsize',14, 'Interpreter', 'latex');
                
            end
          
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            % Assigning file directory to fileName var
            fileName = app.fileDirectory;
            
            % Calling function to read excel file
            [data] = readExcel(fileName);
            
            % Reading in fieldNames property
            editFieldNames.valueNames = app.fieldNames;
            
            % Populating edit fields with excel data
            for ii = 3:length(data.valNames)
                
                % Skipping loading header in assignment
                if ii == 21
                    
                    continue
                end
                
                % Populating the edit fields
                app.(strcat(data.valNames{ii}, 'EditField')).Value = ...
                    data.vals{ii};
                
            end
            

            % Reading units in excel sheet and adjusting drop down menus
            % accordingly
            for ii = 3:29
                
                % Converting Msi dropdown to GPa if unit is selected in 
                % excel
                if (3 <= ii) && (ii <= 5)
                    
                    % If unit in excel is the same as defaul unit (Msi)
                    if strcmp(data.units{ii}, 'Msi') ==  1
                    
                        continue
                    
                    else
                        
                        % Change unit to GPa
                        app.(strcat('DropDown', ...
                            (editFieldNames.valueNames{ii-2}))).Value = ...
                            'GPa';
                        
                    end
                
                % Converting 1/F dropdown to 1/C if unit is selected in
                % excel
                elseif (7 <= ii) && (ii <= 8)
                    
                    % If unit in excel is the same as defaul unit (1/F)
                    if strcmp(data.units{ii}, '1/F') ==  1
                    
                        continue
                    
                    else
                        
                        % Change unit to 1/C
                        app.(strcat('DropDown', ...
                            (editFieldNames.valueNames{ii-2}))).Value = ...
                            '1/C';
                        
                    end
                    
                % Converting ksi dropdown to kPa if unit is selected in
                % excel
                elseif (9 <= ii) && (ii <= 13)
                    
                    % If unit in excel is same as defualt unit (ksi)
                    if strcmp(data.units{ii}, 'ksi') ==  1
                    
                        continue
                    
                    else
                        
                        % Change unit to kPa
                        app.(strcat('DropDown', ...
                            (editFieldNames.valueNames{ii-2}))).Value = ...
                            'kPa';
                        
                    end
                    
                % No units for strength (Strain)
                elseif (14 <= ii) && (ii <= 18)
                    
                    continue
                    
                % Converting in to mm if unit is selected in excel
                elseif ii == 19
                    
                    % If unit in excel is same as defaul unit (in)
                    if strcmp(data.units{ii}, 'in') == 1
                    
                        continue
                    
                    else
                        
                        % Change unit to mm
                        app.DropDownPlyThickness.Value = ...
                            'mm';
                        
                    end
                
                % Checking symmetric box if symmetric is selected in excel
                elseif ii == 20
                    
                    % If symmetric is selcted
                    if strcmp(data.units{ii}, 'symmetric') == 1
                        
                        % Check bock
                        app.SymmetricCheckBox.Value = 1;
                        
                    else
                        
                        continue
                        
                    end
                 
                % Skipping blank row in excel
                elseif ii == 21
                    
                    continue
                    
                % Converting lb to N if unit is selected in excel
                elseif (22 <= ii) && (ii <= 24)
                    
                    % If unit in excel is same as default unit (lb)
                    if strcmp(data.units{ii}, 'lb') ==  1
                    
                        continue
                    
                    else

                        % Change unit to N
                        app.(strcat('DropDown', ...
                            (editFieldNames.valueNames{ii-5}))).Value = ...
                            'N';
                        
                    end
                    
                % Converting lbin to Nm is unit if selcted in excel
                elseif (25 <= ii) && (ii <= 27)
                    
                    % If unit in excel is same as defaul unit (lbin)
                    if strcmp(data.units{ii}, 'lbin') ==  1
                    
                        continue
                    
                    else
                        
                        % Change unit to Nm
                        app.(strcat('DropDown', ...
                            (editFieldNames.valueNames{ii-5}))).Value = ...
                            'Nm';
                        
                    end
                    
                % Converting F to C if unit is selected in excel
                elseif (28 <= ii) && (ii <= 29)
                    
                    % If unit in excel is same as default unit (F)
                    if strcmp(data.units{ii}, 'F') ==  1
                    
                        continue
                    
                    else
                        
                        % Change unit to C
                        app.(strcat('DropDown', ...
                            (editFieldNames.valueNames{ii-4}))).Value = ...
                            'C';
                        
                    end

                end
                
            end
            
           
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
            % Export Data to excel file
            
            % Getting export data property
            toExport = app.exportData;
            
            if strcmp(app.FileNameOptionalEditField.Value, '') == 1
                
                % Creating file name using current date and time
                dateTime = datestr(now, 'ddmmyy-HHMM');
                toExport.fileName = strcat('Composite Analysis - ', ...
                    dateTime, '.xlsm');
                
            else
                
                % If input has file extension
                if contains(app.FileNameOptionalEditField.Value, ".xlsm")
                    
                    toExport.fileName = ...
                        app.FileNameOptionalEditField.Value;
                    
                else
                
                toExport.fileName = ...
                    strcat(app.FileNameOptionalEditField.Value, '.xlsm');
                end
            end
            
            % Writing to excel file
            writecell(toExport.write,toExport.fileName)
            
        end

        % Value changed function: StressSelectionSwitch
        function StressSelectionSwitchValueChanged(app, event)
            
            % Getting export data property
            toExport = app.exportData;
            
            % Getting value from selection switch
            stressSelectionValue = app.StressSelectionSwitch.Value;
            
            if strcmp(stressSelectionValue, 'Local') == 1
                
                % Populating local strain table
                app.UITableStress.Data = toExport.locStress;
                app.UITableStress.ColumnName = ...
                    num2cell(1:length(toExport.locStress));
                app.UITableStress.RowName = {'sigma1', 'sigma2', ...
                    'sigma12'};
                
            elseif strcmp(stressSelectionValue, 'Global') == 1
                
                % Populating global strain table
                app.UITableStress.Data = toExport.globStress;
                app.UITableStress.ColumnName = ...
                    num2cell(1:length(toExport.globStress));
                app.UITableStress.RowName = {'sigmax', 'sigmay', ...
                    'sigmaxy'};
                
            end
            
        end

        % Value changed function: StrainSelectionSwitch
        function StrainSelectionSwitchValueChanged(app, event)
            
            % Getting export data property
            toExport = app.exportData;
            
            % Getting value from selection switch
            strainSelectionValue = app.StrainSelectionSwitch.Value;
            
            
            if strcmp(strainSelectionValue, 'Local') == 1
                
                % Pupulating global stress table
                app.UITableStrain.Data = toExport.locStrain;
                app.UITableStrain.ColumnName = ...
                    num2cell(1:length(toExport.locStrain));
                app.UITableStrain.RowName = {'eps1', 'eps2', ...
                    'gamma12'};
                
            elseif strcmp(strainSelectionValue, 'Global') == 1
                
                % Pupulating global stress table
                app.UITableStrain.Data = toExport.globStrain;
                app.UITableStrain.ColumnName = ...
                    num2cell(1:length(toExport.globStrain));
                app.UITableStrain.RowName = {'epsx', 'epsy', ...
                    'gammaxy'};
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputParametersTab = uitab(app.TabGroup);
            app.InputParametersTab.Title = 'Input Parameters';
            app.LaminateMaterialPropertiesPanel = uipanel(app.InputParametersTab);
            app.LaminateMaterialPropertiesPanel.Title = 'Laminate Material Properties';
            app.E1EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.E1EditFieldLabel.Text = 'E1 ';
            app.E1EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.E2EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.G12EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.G12EditFieldLabel.Text = 'G12';
            app.G12EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.v12EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.v12EditFieldLabel.Text = 'v12';
            app.v12EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.alpha1EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.alpha2EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.DropDownE1 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownE1.Items = {'Msi', 'GPa'};
            app.DropDownE1.Value = 'Msi';
            app.DropDownE2 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownE2.Items = {'Msi', 'GPa'};
            app.DropDownE2.Value = 'Msi';
            app.DropDownG12 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownG12.Items = {'Msi', 'GPa'};
            app.DropDownG12.Value = 'Msi';
            app.DropDownalpha1 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownalpha1.Items = {'1/F', '1/C'};
            app.DropDownalpha1.Value = '1/F';
            app.DropDownalpha2 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownalpha2.Items = {'1/F', '1/C'};
            app.DropDownalpha2.Value = '1/F';
            app.LaminateStrengthPropertiesPanel = uipanel(app.InputParametersTab);
            app.LaminateStrengthPropertiesPanel.Title = 'Laminate Strength Properties';
            app.XEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.XEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XEditField_2Label.Text = 'X''';
            app.XprimeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YEditField_2Label.Text = 'Y''';
            app.YprimeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.SEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.StrengthStressLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.StrengthStressLabel.Text = 'Strength (Stress)';
            app.StrengthStrainLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.StrengthStrainLabel.Text = 'Strength (Strain)';
            app.XeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XeEditFieldLabel.Text = 'Xe';
            app.XeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.DropDownX = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownX.Items = {'ksi', 'kPa'};
            app.DropDownX.Value = 'ksi';
            app.XeEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XeEditField_2Label.Text = 'X''e';
            app.XePrimeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YeEditFieldLabel.Text = 'Ye';
            app.YeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YeEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YeEditField_2Label.Text = 'Y''e';
            app.YePrimeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.SeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.SeEditFieldLabel.Text = 'Se';
            app.SeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.DropDownXprime = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownXprime.Items = {'ksi', 'kPa'};
            app.DropDownXprime.Value = 'ksi';
            app.DropDownY = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownY.Items = {'ksi', 'kPa'};
            app.DropDownY.Value = 'ksi';
            app.DropDownYprime = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownYprime.Items = {'ksi', 'kPa'};
            app.DropDownYprime.Value = 'ksi';
            app.DropDownS = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownS.Items = {'ksi', 'kPa'};
            app.DropDownS.Value = 'ksi';
            app.CompositeMechanicalLoadingConditionsPanel = uipanel(app.InputParametersTab);
            app.CompositeMechanicalLoadingConditionsPanel.Title = 'Composite Mechanical Loading Conditions';
            app.NxEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.NyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.NxyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NxyEditFieldLabel.Text = 'Nxy';
            app.NxyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MxEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MxEditFieldLabel.Text = 'Mx';
            app.MxEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MyEditFieldLabel.Text = 'My';
            app.MyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MxyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MxyEditFieldLabel.Text = 'Mxy';
            app.MxyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.DropDownNx = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDownNx.Items = {'lb', 'N'};
            app.DropDownNx.Value = 'lb';
            app.DropDownMx = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDownMx.Items = {'lbin', 'Nm'};
            app.DropDownMx.Value = 'lbin';
            app.DropDownNy = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDownNy.Items = {'lb', 'N'};
            app.DropDownNy.Value = 'lb';
            app.DropDownNxy = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDownNxy.Items = {'lb', 'N'};
            app.DropDownNxy.Value = 'lb';
            app.DropDownMy = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDownMy.Items = {'lbin', 'Nm'};
            app.DropDownMy.Value = 'lbin';
            app.DropDownMxy = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDownMxy.Items = {'lbin', 'Nm'};
            app.DropDownMxy.Value = 'lbin';
            app.CompositeAnalysisInputParametersLabel = uilabel(app.InputParametersTab);
            app.CompositeAnalysisInputParametersLabel.Text = 'Composite Analysis Input Parameters';
            app.CompositeHygrothermalLoadingConditionsPanel = uipanel(app.InputParametersTab);
            app.CompositeHygrothermalLoadingConditionsPanel.Title = 'Composite Hygrothermal Loading Conditions';
            app.T0EditFieldLabel = uilabel(app.CompositeHygrothermalLoadingConditionsPanel);
            app.T0EditFieldLabel.Text = 'T0';
            app.T0EditField = uieditfield(app.CompositeHygrothermalLoadingConditionsPanel, 'numeric');
            app.TfEditFieldLabel = uilabel(app.CompositeHygrothermalLoadingConditionsPanel);
            app.TfEditFieldLabel.Text = 'Tf';
            app.TfEditField = uieditfield(app.CompositeHygrothermalLoadingConditionsPanel, 'numeric');
            app.DropDownT0 = uidropdown(app.CompositeHygrothermalLoadingConditionsPanel);
            app.DropDownT0.Items = {'F', 'C'};
            app.DropDownT0.Value = 'F';
            app.DropDownTf = uidropdown(app.CompositeHygrothermalLoadingConditionsPanel);
            app.DropDownTf.Items = {'F', 'C'};
            app.DropDownTf.Value = 'F';
            app.ExcelDataOptionalPanel = uipanel(app.InputParametersTab);
            app.ExcelDataOptionalPanel.Title = 'Excel Data (Optional)';
            app.BrowseLabel = uilabel(app.ExcelDataOptionalPanel);
            app.BrowseLabel.Text = 'If Selecting File Other Than "AE535 - Input Data.xlsm" : ';
            app.BrowseButton = uibutton(app.ExcelDataOptionalPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadLabel = uilabel(app.ExcelDataOptionalPanel);
            app.LoadLabel.Text = 'If file "AE 535 - Input Data.xlsm" is in Current Working Directory: ';
            app.LoadDataButton = uibutton(app.ExcelDataOptionalPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DirecotryTextAreaLabel = uilabel(app.ExcelDataOptionalPanel);
            app.DirecotryTextAreaLabel.Visible = 'off';
            app.DirecotryTextAreaLabel.Text = 'Direcotry:';
            app.DirecotryTextArea = uitextarea(app.ExcelDataOptionalPanel);
            app.DirecotryTextArea.Visible = 'off';
            app.Lamp = uilamp(app.ExcelDataOptionalPanel);
            app.LaminatePropertiesPanel = uipanel(app.InputParametersTab);
            app.LaminatePropertiesPanel.Title = 'Laminate Properties';
            app.LayupDelimitedEditFieldLabel = uilabel(app.LaminatePropertiesPanel);
            app.LayupDelimitedEditFieldLabel.Text = 'Layup (''/'' Delimited)';
            app.LayupEditField = uieditfield(app.LaminatePropertiesPanel, 'text');
            app.SymmetricCheckBox = uicheckbox(app.LaminatePropertiesPanel);
            app.SymmetricCheckBox.Text = 'Symmetric';
            app.PlyThicknessEditFieldLabel = uilabel(app.LaminatePropertiesPanel);
            app.PlyThicknessEditFieldLabel.Text = 'PlyThickness';
            app.PlyThicknessEditField = uieditfield(app.LaminatePropertiesPanel, 'numeric');
            app.DropDownPlyThickness = uidropdown(app.LaminatePropertiesPanel);
            app.DropDownPlyThickness.Items = {'in', 'mm'};
            app.DropDownPlyThickness.Value = 'in';
            app.RunProgramPanel = uipanel(app.InputParametersTab);
            app.RunProgramPanel.Title = 'Run Program';
            app.RunButton = uibutton(app.RunProgramPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnalysisOutputTab = uitab(app.TabGroup);
            app.AnalysisOutputTab.Title = 'Analysis Output';
            app.StressPanel = uipanel(app.AnalysisOutputTab);
            app.StressPanel.Title = 'Stress';
            app.UITableStress = uitable(app.StressPanel);
            app.UITableStress.ColumnName = {''; ''; ''; ''};
            app.UITableStress.RowName = {};
            app.StrainPanel = uipanel(app.AnalysisOutputTab);
            app.StrainPanel.Title = 'Strain';
            app.UITableStrain = uitable(app.StrainPanel);
            app.UITableStrain.ColumnName = {''; ''; ''; ''};
            app.UITableStrain.RowName = {};
            app.MidplainStrainPanel = uipanel(app.AnalysisOutputTab);
            app.MidplainStrainPanel.Title = 'Midplain Strain';
            app.UITableMidplaneStrain = uitable(app.MidplainStrainPanel);
            app.UITableMidplaneStrain.ColumnName = {''; ''; ''; ''};
            app.UITableMidplaneStrain.RowName = {};
            app.MidplainCurveraturePanel = uipanel(app.AnalysisOutputTab);
            app.MidplainCurveraturePanel.Title = 'Midplain Curverature';
            app.UITableMidplaneCurverature = uitable(app.MidplainCurveraturePanel);
            app.UITableMidplaneCurverature.ColumnName = {''; ''; ''; ''};
            app.UITableMidplaneCurverature.RowName = {};
            app.TotalLoadingPanel = uipanel(app.AnalysisOutputTab);
            app.TotalLoadingPanel.Title = 'Total Loading';
            app.UITableTotalLoads = uitable(app.TotalLoadingPanel);
            app.UITableTotalLoads.ColumnName = {''; ''; ''; ''};
            app.UITableTotalLoads.RowName = {};
            app.FailureCriteriaPanel = uipanel(app.AnalysisOutputTab);
            app.FailureCriteriaPanel.Title = 'Failure Criteria';
            app.UITableFaliureCriteria = uitable(app.FailureCriteriaPanel);
            app.UITableFaliureCriteria.ColumnName = {''; ''; ''; ''};
            app.UITableFaliureCriteria.RowName = {};
            app.SaveDataPanel = uipanel(app.AnalysisOutputTab);
            app.SaveDataPanel.Title = 'Save Data';
            app.WritetoExcelLabel = uilabel(app.SaveDataPanel);
            app.WritetoExcelLabel.Text = 'Write to Excel:';
            app.ExportButton = uibutton(app.SaveDataPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FileNameOptionalEditFieldLabel = uilabel(app.SaveDataPanel);
            app.FileNameOptionalEditFieldLabel.Text = 'File Name (Optional)';
            app.FileNameOptionalEditField = uieditfield(app.SaveDataPanel, 'text');
            app.EffectiveConstatnsPanel = uipanel(app.AnalysisOutputTab);
            app.EffectiveConstatnsPanel.Title = 'Effective Constatns';
            app.UITableEffectiveLaminateConstants = uitable(app.EffectiveConstatnsPanel);
            app.UITableEffectiveLaminateConstants.ColumnName = {''; ''; ''; ''};
            app.UITableEffectiveLaminateConstants.RowName = {};
            app.StressSelectionLabel = uilabel(app.AnalysisOutputTab);
            app.StressSelectionLabel.Text = 'Stress Selection:';
            app.StressSelectionSwitch = uiswitch(app.AnalysisOutputTab, 'slider');
            app.StressSelectionSwitch.Items = {'Global', 'Local'};
            app.StressSelectionSwitch.ValueChangedFcn = createCallbackFcn(app, @StressSelectionSwitchValueChanged, true);
            app.StressSelectionSwitch.Value = 'Global';
            app.StrainSelectionLabel = uilabel(app.AnalysisOutputTab);
            app.StrainSelectionLabel.Text = 'Strain Selection:';
            app.StrainSelectionSwitch = uiswitch(app.AnalysisOutputTab, 'slider');
            app.StrainSelectionSwitch.Items = {'Global', 'Local'};
            app.StrainSelectionSwitch.ValueChangedFcn = createCallbackFcn(app, @StrainSelectionSwitchValueChanged, true);
            app.StrainSelectionSwitch.Value = 'Global';
            app.GlobalStressPlotsTab = uitab(app.TabGroup);
            app.GlobalStressPlotsTab.Title = 'Global Stress Plots';
            app.GlobalStressPlotsPanel = uipanel(app.GlobalStressPlotsTab);
            app.GlobalStressPlotsPanel.Title = 'Global Stress Plots';
            app.GlobalStrainPlotsTab = uitab(app.TabGroup);
            app.GlobalStrainPlotsTab.Title = 'Global Strain Plots';
            app.GlobalStrainPlotsPanel = uipanel(app.GlobalStrainPlotsTab);
            app.GlobalStrainPlotsPanel.Title = 'Global Strain Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end